classdef Term_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenSatelliteScenarioViewerButton  matlab.ui.control.Button
        SatelliteOrbitSimulationProgramLabel  matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ConstellationButtonGroup        matlab.ui.container.ButtonGroup
        BDSButton                       matlab.ui.control.ToggleButton
        QZSSButton                      matlab.ui.control.ToggleButton
        GPSButton                       matlab.ui.control.ToggleButton
        MessageWindowTextArea           matlab.ui.control.TextArea
        MessageWindowTextAreaLabel      matlab.ui.control.Label
        CurrentEpochEditField           matlab.ui.control.EditField
        CurrentEpochEditFieldLabel      matlab.ui.control.Label
        SatelliteGroundTrajectoryPanel  matlab.ui.container.Panel
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        LongitudeSpinner                matlab.ui.control.Spinner
        LongitudeSpinnerLabel           matlab.ui.control.Label
        LatitudeSpinnerLabel            matlab.ui.control.Label
        LatitudeSpinner                 matlab.ui.control.Spinner
        Label                           matlab.ui.control.Label
        LoadNavigationDataButton        matlab.ui.control.Button
        ReceiverPositionPanel           matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        SkyplotPanel                    matlab.ui.container.Panel
    end

    
    properties (Access = private)
        gx
        raw_nav
        Position
        mu=3.986004418*10^14;
        current_time;
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadNavigationDataButton
        function LoadNavigationDataButtonPushed(app, event)
            uiopen('*.mat');
            app.MessageWindowTextArea.Value={'Running...'};
            pause(1)
            app.MessageWindowTextArea.Value={'Running...(1/3)'};
            pause(1)
            app.MessageWindowTextArea.Value={'Running...(2/3)'};
            pause(1)
            load Pos.mat;
            load nav.mat;
            app.Position=Pos;
            app.raw_nav=nav;
            app.gx=geoaxes(app.SatelliteGroundTrajectoryPanel);
            
            geoscatter(app.gx,app.Position.GPS.LLH(:,1),app.Position.GPS.LLH(:,2),[],[0.4660 0.6740 0.1880],'.')
            % geolimits(gx,[-75 75],[-90 360])
            app.MessageWindowTextArea.Value={'Navigation data successfully loaded!' ...
                newline 'Select time, receiver position, constellation to see more data.'};
            app.Label.Text='2023-04-29 00:00:00';
            app.Label_2.Text='2023-04-30 00:00:00';
            [Azel_GPS(:,1),Azel_GPS(:,2)]=ecef2aer(app.Position.GPS.ECEF(:,1),app.Position.GPS.ECEF(:,2),app.Position.GPS.ECEF(:,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
            idx=find(Azel_GPS(:,2)>0);
            ax=app.SkyplotPanel;
            skyplot(ax,Azel_GPS(idx,1),Azel_GPS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4660 0.6740 0.1880],'MarkerEdgeColor',[0.4660 0.6740 0.1880])
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            switch app.ConstellationButtonGroup.SelectedObject
                case app.GPSButton
                    hold(app.gx,"off")
                    geoscatter(app.gx,app.Position.GPS.LLH(1:floor(value)+1,1),app.Position.GPS.LLH(1:floor(value)+1,2),[],[0.4660 0.6740 0.1880],'.')
                    hold(app.gx,"on")
                    geoscatter(app.gx,app.Position.GPS.LLH(floor(value)+1,1),app.Position.GPS.LLH(floor(value)+1,2),100,[1 0 0],'pentagram')
                    app.Label.Text='2023-04-29 00:00:00';
                    app.Label_2.Text='2023-04-30 00:00:00';
                    date1=cal2time([2023 04 29 00 00 00]);
                    time=date1+floor(value);
                    date2=time2cal(time);
                    app.current_time=date2;
                    str=sprintf('%04d-%02d-%02d %02d:%02d:%02d',date2(1),date2(2),date2(3),date2(4),date2(5),date2(6));
                    app.CurrentEpochEditField.Value=str;
                    [Azel_GPS(:,1),Azel_GPS(:,2)]=ecef2aer(app.Position.GPS.ECEF(1:floor(value)+1,1),app.Position.GPS.ECEF(1:floor(value)+1,2),app.Position.GPS.ECEF(1:floor(value)+1,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_GPS(1:floor(value)+1,2)>0);
                    ax=app.SkyplotPanel;
                    if isempty(idx)==1
                        app.MessageWindowTextArea.Value={'Satellite is not visible at your site!!'};
                        app.MessageWindowTextArea.FontColor=[1 0 0];
                    else
                        app.MessageWindowTextArea.Value={''};
                    end
                    skyplot(ax,Azel_GPS(idx,1),Azel_GPS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4660 0.6740 0.1880],'MarkerEdgeColor',[0.4660 0.6740 0.1880])
                case app.QZSSButton
                    hold(app.gx,"off")
                    geoscatter(app.gx,app.Position.QZSS.LLH(1:floor(value)+1,1),app.Position.QZSS.LLH(1:floor(value)+1,2),[],[0.4940 0.1840 0.5560],'.')
                    hold(app.gx,"on")
                    geoscatter(app.gx,app.Position.QZSS.LLH(floor(value)+1,1),app.Position.QZSS.LLH(floor(value)+1,2),100,[1 0 0],'pentagram')
                    app.Label.Text='2023-05-09 02:00:00';
                    app.Label_2.Text='2023-05-09 02:00:00';
                    date1=cal2time([2023 05 09 02 00 00]);
                    time=date1+floor(value);
                    date2=time2cal(time);
                    app.current_time=date2;
                    str=sprintf('%04d-%02d-%02d %02d:%02d:%02d',date2(1),date2(2),date2(3),date2(4),date2(5),date2(6));
                    app.CurrentEpochEditField.Value=str;
                    [Azel_QZSS(:,1),Azel_QZSS(:,2)]=ecef2aer(app.Position.QZSS.ECEF(1:floor(value)+1,1),app.Position.QZSS.ECEF(1:floor(value)+1,2),app.Position.QZSS.ECEF(1:floor(value)+1,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_QZSS(1:floor(value)+1,2)>0);
                    ax=app.SkyplotPanel;
                    if isempty(idx)==1
                        app.MessageWindowTextArea.Value={'Satellite is not visible at your site!!'};
                        app.MessageWindowTextArea.FontColor=[1 0 0];
                    else
                        app.MessageWindowTextArea.Value={''};
                    end
                    skyplot(ax,Azel_QZSS(idx,1),Azel_QZSS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4940 0.1840 0.5560],'MarkerEdgeColor',[0.4940 0.1840 0.5560])
                case app.BDSButton
                    hold(app.gx,"off")
                    geoscatter(app.gx,app.Position.BDS.LLH(1:floor(value)+1,1),app.Position.BDS.LLH(1:floor(value)+1,2),[],[0.9290 0.6940 0.1250],'.')
                    hold(app.gx,"on")
                    geoscatter(app.gx,app.Position.BDS.LLH(floor(value)+1,1),app.Position.BDS.LLH(floor(value)+1,2),100,[1 0 0],'pentagram')
                    app.Label.Text='2023-02-26 00:00:00';
                    app.Label_2.Text='2023-02-26 00:00:00';
                    date1=cal2time([2023 02 26 00 00 00]);
                    time=date1+floor(value);
                    date2=time2cal(time);
                    app.current_time=date2;
                    str=sprintf('%04d-%02d-%02d %02d:%02d:%02d',date2(1),date2(2),date2(3),date2(4),date2(5),date2(6));
                    app.CurrentEpochEditField.Value=str;
                    [Azel_BDS(:,1),Azel_BDS(:,2)]=ecef2aer(app.Position.BDS.ECEF(1:floor(value)+1,1),app.Position.BDS.ECEF(1:floor(value)+1,2),app.Position.BDS.ECEF(1:floor(value)+1,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_BDS(1:floor(value)+1,2)>0);
                    ax=app.SkyplotPanel;
                    if isempty(idx)==1
                        app.MessageWindowTextArea.Value={'Satellite is not visible at your site!!'};
                        app.MessageWindowTextArea.FontColor=[1 0 0];
                    else
                        app.MessageWindowTextArea.Value={''};
                    end
                    
                    skyplot(ax,Azel_BDS(idx,1),Azel_BDS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.9290 0.6940 0.1250],'MarkerEdgeColor',[0.9290 0.6940 0.1250])
            end
        end

        % Selection changed function: ConstellationButtonGroup
        function ConstellationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConstellationButtonGroup.SelectedObject;
            app.CurrentEpochEditField.Value='';
            switch selectedButton
                case app.GPSButton
                    hold(app.gx,"off")
                    app.TimeSlider.Value=0;
                    geoscatter(app.gx,app.Position.GPS.LLH(:,1),app.Position.GPS.LLH(:,2),[],[0.4660 0.6740 0.1880],'.')
                    app.Label.Text='2023-04-29 00:00:00';
                    app.Label_2.Text='2023-04-30 00:00:00';
                    [Azel_GPS(:,1),Azel_GPS(:,2)]=ecef2aer(app.Position.GPS.ECEF(:,1),app.Position.GPS.ECEF(:,2),app.Position.GPS.ECEF(:,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_GPS(:,2)>0);
                    ax=app.SkyplotPanel;
                    skyplot(ax,Azel_GPS(idx,1),Azel_GPS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4660 0.6740 0.1880],'MarkerEdgeColor',[0.4660 0.6740 0.1880])
                case app.QZSSButton
                    hold(app.gx,"off")
                    app.TimeSlider.Value=0;
                    geoscatter(app.gx,app.Position.QZSS.LLH(:,1),app.Position.QZSS.LLH(:,2),[],[0.4940 0.1840 0.5560],'.')
                    app.Label.Text='2023-05-09 02:00:00';
                    app.Label_2.Text='2023-05-09 02:00:00';
                    [Azel_QZSS(:,1),Azel_QZSS(:,2)]=ecef2aer(app.Position.QZSS.ECEF(:,1),app.Position.QZSS.ECEF(:,2),app.Position.QZSS.ECEF(:,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_QZSS(:,2)>0);
                    ax=app.SkyplotPanel;
                    skyplot(ax,Azel_QZSS(idx,1),Azel_QZSS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4940 0.1840 0.5560],'MarkerEdgeColor',[0.4940 0.1840 0.5560])
                case app.BDSButton
                    hold(app.gx,"off")
                    app.TimeSlider.Value=0;
                    geoscatter(app.gx,app.Position.BDS.LLH(:,1),app.Position.BDS.LLH(:,2),[],[0.9290 0.6940 0.1250],'.')
                    app.Label.Text='2023-02-26 00:00:00';
                    app.Label_2.Text='2023-02-26 00:00:00';
                    [Azel_BDS(:,1),Azel_BDS(:,2)]=ecef2aer(app.Position.BDS.ECEF(:,1),app.Position.BDS.ECEF(:,2),app.Position.BDS.ECEF(:,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_BDS(:,2)>0);
                    ax=app.SkyplotPanel;
                    skyplot(ax,Azel_BDS(idx,1),Azel_BDS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.9290 0.6940 0.1250],'MarkerEdgeColor',[0.9290 0.6940 0.1250])
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.TimeSlider.Value;
            switch app.ConstellationButtonGroup.SelectedObject
                case app.GPSButton
                    hold(app.gx,"off")
                    geoscatter(app.gx,app.Position.GPS.LLH(1:floor(value)+1,1),app.Position.GPS.LLH(1:floor(value)+1,2),[],[0.4660 0.6740 0.1880],'.')
                    hold(app.gx,"on")
                    geoscatter(app.gx,app.Position.GPS.LLH(floor(value)+1,1),app.Position.GPS.LLH(floor(value)+1,2),100,[1 0 0],'pentagram')
                    app.Label_2.Text='2023-04-30 00:00:00';
                    date1=cal2time([2023 04 29 00 00 00]);
                    time=date1+floor(value);
                    date2=time2cal(time);
                    app.current_time=date2;
                    str=sprintf('%04d-%02d-%02d %02d:%02d:%02d',date2(1),date2(2),date2(3),date2(4),date2(5),date2(6));
                    app.CurrentEpochEditField.Value=str;
                    [Azel_GPS(:,1),Azel_GPS(:,2)]=ecef2aer(app.Position.GPS.ECEF(1:floor(value)+1,1),app.Position.GPS.ECEF(1:floor(value)+1,2),app.Position.GPS.ECEF(1:floor(value)+1,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_GPS(1:floor(value)+1,2)>0);
                    ax=app.SkyplotPanel;
                    if isempty(idx)==1
                        app.MessageWindowTextArea.Value={'Satellite is not visible at your site!!'};
                        app.MessageWindowTextArea.FontColor=[1 0 0];
                    else
                        app.MessageWindowTextArea.Value={''};
                    end
                    skyplot(ax,Azel_GPS(idx,1),Azel_GPS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4660 0.6740 0.1880],'MarkerEdgeColor',[0.4660 0.6740 0.1880])
                case app.QZSSButton
                    hold(app.gx,"off")
                    geoscatter(app.gx,app.Position.QZSS.LLH(1:floor(value)+1,1),app.Position.QZSS.LLH(1:floor(value)+1,2),[],[0.4940 0.1840 0.5560],'.')
                    hold(app.gx,"on")
                    geoscatter(app.gx,app.Position.QZSS.LLH(floor(value)+1,1),app.Position.QZSS.LLH(floor(value)+1,2),100,[1 0 0],'pentagram')
                    app.Label_2.Text='2023-05-09 02:00:00';
                    date1=cal2time([2023 05 09 02 00 00]);
                    time=date1+floor(value);
                    date2=time2cal(time);
                    app.current_time=date2;
                    str=sprintf('%04d-%02d-%02d %02d:%02d:%02d',date2(1),date2(2),date2(3),date2(4),date2(5),date2(6));
                    app.CurrentEpochEditField.Value=str;
                    [Azel_QZSS(:,1),Azel_QZSS(:,2)]=ecef2aer(app.Position.QZSS.ECEF(1:floor(value)+1,1),app.Position.QZSS.ECEF(1:floor(value)+1,2),app.Position.QZSS.ECEF(1:floor(value)+1,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_QZSS(1:floor(value)+1,2)>0);
                    ax=app.SkyplotPanel;
                    if isempty(idx)==1
                        app.MessageWindowTextArea.Value={'Satellite is not visible at your site!!'};
                        app.MessageWindowTextArea.FontColor=[1 0 0];
                    else
                        app.MessageWindowTextArea.Value={''};
                    end
                    skyplot(ax,Azel_QZSS(idx,1),Azel_QZSS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.4940 0.1840 0.5560],'MarkerEdgeColor',[0.4940 0.1840 0.5560])
                case app.BDSButton
                    hold(app.gx,"off")
                    geoscatter(app.gx,app.Position.BDS.LLH(1:floor(value)+1,1),app.Position.BDS.LLH(1:floor(value)+1,2),[],[0.9290 0.6940 0.1250],'.')
                    hold(app.gx,"on")
                    geoscatter(app.gx,app.Position.BDS.LLH(floor(value)+1,1),app.Position.BDS.LLH(floor(value)+1,2),100,[1 0 0],'pentagram')
                    app.Label_2.Text='2023-02-26 00:00:00';
                    date1=cal2time([2023 02 26 00 00 00]);
                    time=date1+floor(value);
                    date2=time2cal(time);
                    app.current_time=date2;
                    str=sprintf('%04d-%02d-%02d %02d:%02d:%02d',date2(1),date2(2),date2(3),date2(4),date2(5),date2(6));
                    app.CurrentEpochEditField.Value=str;
                    [Azel_BDS(:,1),Azel_BDS(:,2)]=ecef2aer(app.Position.BDS.ECEF(1:floor(value)+1,1),app.Position.BDS.ECEF(1:floor(value)+1,2),app.Position.BDS.ECEF(1:floor(value)+1,3),app.LatitudeSpinner.Value,app.LongitudeSpinner.Value,app.HeightSpinner.Value,wgs84Ellipsoid);
                    idx=find(Azel_BDS(1:floor(value)+1,2)>0);
                    ax=app.SkyplotPanel;
                    if isempty(idx)==1
                        app.MessageWindowTextArea.Value={'Satellite is not visible at your site!!'};
                        app.MessageWindowTextArea.FontColor=[1 0 0];
                    else
                        app.MessageWindowTextArea.Value={''};
                    end
                    skyplot(ax,Azel_BDS(idx,1),Azel_BDS(idx,2),'MarkerSizeData',25,'MarkerFaceColor',[0.9290 0.6940 0.1250],'MarkerEdgeColor',[0.9290 0.6940 0.1250])
            end

        end

        % Button pushed function: OpenSatelliteScenarioViewerButton
        function OpenSatelliteScenarioViewerButtonPushed(app, event)
            switch app.ConstellationButtonGroup.SelectedObject
                case app.GPSButton
                    app.MessageWindowTextArea.FontColor=[0 0 0];
                    app.MessageWindowTextArea.Value={'Running...'};
                    load nu_GPS.mat
                    nu_GPS=rad2deg(nu);
                    sc=satelliteScenario(datetime([2023 04 29 00 00 00]),datetime([2023 04 30 00 00 00]),1);
                    gps=satellite(sc,app.raw_nav.GPS.a,app.raw_nav.GPS.e,rad2deg(app.raw_nav.GPS.i),rad2deg(app.raw_nav.GPS.OMEGA),rad2deg(app.raw_nav.GPS.omega),nu_GPS(1));
                    gs=groundStation(sc,app.LatitudeSpinner.Value,app.LongitudeSpinner.Value);
                    ac=access(gps,gs);
                    intvls=accessIntervals(ac);
                    gt=groundTrack(gps);
                    play(sc)
                    app.MessageWindowTextArea.Value={'Completed!'};
                case app.QZSSButton
                    app.MessageWindowTextArea.FontColor=[0 0 0];
                    app.MessageWindowTextArea.Value={'Running...'};
                    load nu_QZSS.mat
                    nu_QZSS=rad2deg(nu);
                    sc=satelliteScenario(datetime([2023 05 09 02 00 00]),datetime([2023 05 10 02 00 00]),1);
                    qzss=satellite(sc,app.raw_nav.QZSS.a,app.raw_nav.QZSS.e,rad2deg(app.raw_nav.QZSS.i),rad2deg(app.raw_nav.QZSS.OMEGA),rad2deg(app.raw_nav.QZSS.omega),nu_QZSS(1));
                    gs=groundStation(sc,app.LatitudeSpinner.Value,app.LongitudeSpinner.Value);
                    ac=access(qzss,gs);
                    intvls=accessIntervals(ac);
                    gt=groundTrack(qzss);
                    play(sc)
                    app.MessageWindowTextArea.Value={'Completed!'};
                case app.BDSButton
                    app.MessageWindowTextArea.FontColor=[0 0 0];
                    app.MessageWindowTextArea.Value={'Running...'};
                    load nu_BDS.mat
                    nu_BDS=rad2deg(nu);
                    sc=satelliteScenario(datetime([2023 02 26 00 00 00]),datetime([2023 02 27 00 00 00]),1);
                    bds=satellite(sc,app.raw_nav.BDS.a,app.raw_nav.BDS.e,rad2deg(app.raw_nav.BDS.i),rad2deg(app.raw_nav.BDS.OMEGA),rad2deg(app.raw_nav.BDS.omega),nu_BDS(1));
                    gs=groundStation(sc,app.LatitudeSpinner.Value,app.LongitudeSpinner.Value);
                    ac=access(bds,gs);
                    intvls=accessIntervals(ac);
                    gt=groundTrack(bds);
                    play(sc)
                    app.MessageWindowTextArea.Value={'Completed!'};
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SkyplotPanel = uipanel(app.UIFigure);
            app.SkyplotPanel.Title = 'Skyplot';
            app.ReceiverPositionPanel = uipanel(app.UIFigure);
            app.ReceiverPositionPanel.Title = 'Receiver Position';
            app.RunButton = uibutton(app.ReceiverPositionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadNavigationDataButton = uibutton(app.UIFigure, 'push');
            app.LoadNavigationDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNavigationDataButtonPushed, true);
            app.LoadNavigationDataButton.Text = 'Load Navigation Data';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LatitudeSpinner = uispinner(app.UIFigure);
            app.LatitudeSpinner.Value = 37.55;
            app.LatitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LatitudeSpinnerLabel.Text = 'Latitude';
            app.LongitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LongitudeSpinnerLabel.Text = 'Longitude';
            app.LongitudeSpinner = uispinner(app.UIFigure);
            app.LongitudeSpinner.Value = 127.07;
            app.HeightSpinnerLabel = uilabel(app.UIFigure);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.UIFigure);
            app.HeightSpinner.Value = 87.577;
            app.SatelliteGroundTrajectoryPanel = uipanel(app.UIFigure);
            app.SatelliteGroundTrajectoryPanel.Title = 'Satellite Ground Trajectory';
            app.CurrentEpochEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentEpochEditFieldLabel.Text = 'Current Epoch';
            app.CurrentEpochEditField = uieditfield(app.UIFigure, 'text');
            app.MessageWindowTextAreaLabel = uilabel(app.UIFigure);
            app.MessageWindowTextAreaLabel.Text = 'Message Window';
            app.MessageWindowTextArea = uitextarea(app.UIFigure);
            app.MessageWindowTextArea.Value = {'Click the button below to load navigation data.'};
            app.ConstellationButtonGroup = uibuttongroup(app.UIFigure);
            app.ConstellationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConstellationButtonGroupSelectionChanged, true);
            app.ConstellationButtonGroup.Title = 'Constellation';
            app.GPSButton = uitogglebutton(app.ConstellationButtonGroup);
            app.GPSButton.Text = 'GPS';
            app.GPSButton.Value = true;
            app.QZSSButton = uitogglebutton(app.ConstellationButtonGroup);
            app.QZSSButton.Text = 'QZSS';
            app.BDSButton = uitogglebutton(app.ConstellationButtonGroup);
            app.BDSButton.Text = 'BDS';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0 86400];
            app.TimeSlider.MajorTicks = [0 14400 28800 43200 57600 72000 86400];
            app.TimeSlider.MajorTickLabels = {'+0H', '+4H', '+8H', '+12H', '+16H', '+20H', '+24H'};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.MinorTicks = [];
            app.SatelliteOrbitSimulationProgramLabel = uilabel(app.UIFigure);
            app.SatelliteOrbitSimulationProgramLabel.Text = 'Satellite Orbit Simulation Program';
            app.OpenSatelliteScenarioViewerButton = uibutton(app.UIFigure, 'push');
            app.OpenSatelliteScenarioViewerButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSatelliteScenarioViewerButtonPushed, true);
            app.OpenSatelliteScenarioViewerButton.Text = 'Open Satellite Scenario Viewer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Term_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end